classdef plotpeak_v1_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RUNButton                       matlab.ui.control.Button
        plotpeak_v15Label               matlab.ui.control.Label
        FinishedLabel                   matlab.ui.control.Label
        ProcessingLabel                 matlab.ui.control.Label
        All_profilesimageButtonGroup    matlab.ui.container.ButtonGroup
        IntervalEditFieldLabel          matlab.ui.control.Label
        IntervalEditField               matlab.ui.control.NumericEditField
        CroplargepeakButton             matlab.ui.control.RadioButton
        ShowfullpeakButton              matlab.ui.control.RadioButton
        PrecisionEditFieldLabel         matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        ForcexaxisextentCheckBox        matlab.ui.control.CheckBox
        PrecisionEditField              matlab.ui.control.NumericEditField
        showdspacingCheckBox            matlab.ui.control.CheckBox
        show2thetaCheckBox              matlab.ui.control.CheckBox
        hEditField                      matlab.ui.control.NumericEditField
        dEditField                      matlab.ui.control.NumericEditField
        TargetwavelengthEditField       matlab.ui.control.NumericEditField
        OriginalwavelengthEditField     matlab.ui.control.NumericEditField
        ChangewavelenthCheckBox         matlab.ui.control.CheckBox
        CropfilenameEditField           matlab.ui.control.NumericEditField
        HeaderlinesEditField            matlab.ui.control.NumericEditField
        FilefilterEditField             matlab.ui.control.EditField
        InstructionsLabel               matlab.ui.control.Label
        FilefilterEditFieldLabel        matlab.ui.control.Label
        hEditFieldLabel                 matlab.ui.control.Label
        dEditFieldLabel                 matlab.ui.control.Label
        CropfilenameEditFieldLabel      matlab.ui.control.Label
        HeaderlinesEditFieldLabel       matlab.ui.control.Label
        TargetwavelengthEditFieldLabel  matlab.ui.control.Label
        OriginalwavelengthEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.ProcessingLabel.Visible = 'on';
            app.FinishedLabel.Visible = 'off';
            npath = mfilename('fullpath');
            npath = (npath(1:length(strtrim(npath))-13));
            cd (npath)
            %%%%%[section 1]%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            ori_wl=app.OriginalwavelengthEditField.Value;
            tag_wl=app.TargetwavelengthEditField.Value;
            xyes=ls (app.FilefilterEditField.Value);
            remove_line=app.HeaderlinesEditField.Value;
            filenamerm=app.CropfilenameEditField.Value;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if  app.ChangewavelenthCheckBox.Value ~= true
                ori_wl=tag_wl;
            end
            fn = size(xyes,1); % number of files
            for f = 1: fn
               file_name = strtrim(xyes(f,:));
               fileID = fopen(file_name,'r');
               total_line = 0;
               tline = fgetl(fileID);
               while ischar(tline)   %count total line in file
                     tline = fgetl(fileID);
                     total_line = total_line+1;
               end
               line_number = total_line - remove_line;
               frewind(fileID);
               for i = 1 : remove_line  %skip headings
                    tline = fgets(fileID);
               end
               data=zeros(line_number,3);
               for line = 1 : line_number
                   data(line,:) = str2num(fgets(fileID));
                   data(line,1) = asind(sin(data(line,1)./2.*pi./180)./ori_wl.*tag_wl).*2;  %convert wave length 
               end
               fclose(fileID);
               new_file_name = (file_name(1:length(strtrim(file_name))-filenamerm)); %delete "_mar3450_A92.xye"
               fileID2 = fopen((new_file_name)+(".csv"),'w'); %save csv file
               fprintf(fileID2,('%s'),num2str(data(:,1))+", "+num2str(data(:,2))+newline);
               fclose(fileID2);
               figure('outerposition',get(0,'screensize'))
               title(new_file_name,'Interpreter','none') %figure title
               %Single plot
               %%%%%[section 2]%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               d=app.dEditField.Value; %compare to nearby data point (>1 integer)
               h=app.hEditField.Value; %higher than those data points by (>0 floating point)
               accurate=app.PrecisionEditField.Value; %accuracy, default 0.01
               xlabel("2theta")    %figure x axis
               ylabel("Intensity") %figure y axis
               %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
               hold on
               plot(data(:,1),data(:,2))
               if app.ForcexaxisextentCheckBox.Value == true
                   xtend=app.EditField.Value;
               else
                   xtend=max(data(:,1))+(max(data(:,1))-min(data(:,1)))/50;
               end
               axis([(min(data(:,1))-(max(data(:,1))-min(data(:,1)))/50),xtend,0,(max(data(:,2))*1.1)]);
               for i = 1:1:length(data)-2*d
                  if  data(i+d,2)-data(i,2) > h && data(i+d,2)-data(i+2*d,2) > h && data(i+d,2)-data(i+d+1,2) > 0 && data(i+d,2)-data(i+d-1,2) > 0
                      plot(data(i+d,1),data(i+d,2),'-o','color','r')
                      if (app.show2thetaCheckBox.Value == true) && (app.showdspacingCheckBox.Value == true)					   
                          ht=text(data(i+d,1),data(i+d,2)+(max(data(:,2))/50),[num2str(round(data(i+d,1)*10^accurate)/10^accurate) ', ' num2str(round(tag_wl/(2*sin(data(i+d,1)/2/180*pi))*10^accurate)/10^accurate)]);
                      elseif (app.show2thetaCheckBox.Value ~= true) && (app.showdspacingCheckBox.Value == true)
                          ht=text(data(i+d,1),data(i+d,2)+(max(data(:,2))/50),[num2str(round(tag_wl/(2*sin(data(i+d,1)/2/180*pi))*10^accurate)/10^accurate)]);
                      elseif (app.show2thetaCheckBox.Value == true) && (app.showdspacingCheckBox.Value ~= true)
                          ht=text(data(i+d,1),data(i+d,2)+(max(data(:,2))/50),[num2str(round(data(i+d,1)*10^accurate)/10^accurate)]);
                      else
                          ht=text(data(i+d,1),data(i+d,2)+(max(data(:,2))/50),"");
                      end
                      %ht=text(data(i+d,1),data(i+d,2)+(max(data(:,2))/50),[num2str(round(data(i+d,1)*10^accurate)/10^accurate) ', ' num2str(round(tag_wl/(2*sin(data(i+d,1)/2/180*pi))*10^accurate)/10^accurate)]);
                      set(ht,'Rotation',90,'fontsize',8)
                  end
               end
               print(gcf,new_file_name,'-dpng')
               hold off
               close
            end

            %Muti plot
            figure('outerposition',get(0,'screensize'))
            %%%%%[section 3]%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            figtitle="All_profiles"; %title 
            d=app.dEditField.Value; %compare to nearby data point (>1 integer)
            h=app.hEditField.Value; %higher than those data points by (>0 floating point)
            sp=app.IntervalEditField.Value; %space between each profile
            xlabel("2theta")    %figure x axis
            ylabel("Intensity") %figure y axis
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            title(figtitle,'Interpreter','none') 
            hold on
            csvs = ls ('*.csv'); % list all .xye files in the folder 
            cfn = size(csvs,1); % number of files
            maxy=zeros(cfn,1);
            maxx=zeros(cfn,1);
            minx=zeros(cfn,1);
            for n=1:cfn
                dataall=load(csvs(n,:));
                dataall(:,2)=dataall(:,2)+((n-1)*sp);
                if app.ShowfullpeakButton.Value == true
                    texth=(max(dataall(:,2))/50);
                elseif app.CroplargepeakButton.Value == true
                    texth=((cfn/2)*sp/50);
                end
                maxy(n)=max(dataall(:,2));
                maxx(n)=max(dataall(:,1));
                minx(n)=min(dataall(:,1));
                plot(dataall(:,1),dataall(:,2));
                file_name = strtrim(csvs(n,:));
                file_name = (file_name(1:length(file_name)-4));
                text(dataall(1,1),(n-1)*sp+0.2*sp,file_name,'Interpreter','none')
                for i = 1:1:length(dataall)-2*d
                   if  dataall(i+d,2)-dataall(i,2) > h && dataall(i+d,2)-dataall(i+2*d,2) > h && dataall(i+d,2)-dataall(i+d+1,2) > 0 && dataall(i+d,2)-dataall(i+d-1,2) > 0
                       plot(dataall(i+d,1),dataall(i+d,2),'-o','color','r')
                       if (app.show2thetaCheckBox.Value == true) && (app.showdspacingCheckBox.Value == true)
                           ht=text(dataall(i+d,1),dataall(i+d,2)+texth,[num2str(round(dataall(i+d,1)*10^accurate)/10^accurate) ', ' num2str(round(tag_wl/(2*sin(dataall(i+d,1)/2/180*pi))*10^accurate)/10^accurate)]);
                       elseif (app.show2thetaCheckBox.Value ~= true) && (app.showdspacingCheckBox.Value == true)
                           ht=text(dataall(i+d,1),dataall(i+d,2)+texth,num2str(round(tag_wl/(2*sin(dataall(i+d,1)/2/180*pi))*10^accurate)/10^accurate));
                       elseif (app.show2thetaCheckBox.Value == true) && (app.showdspacingCheckBox.Value ~= true)
                           ht=text(dataall(i+d,1),dataall(i+d,2)+texth,num2str(round(dataall(i+d,1)*10^accurate)/10^accurate));
                       else
                           ht=text(dataall(i+d,1),dataall(i+d,2)+texth,"");
                       end
                       set(ht,'Rotation',90,'fontsize',8)
                   end
                end
            end
            if app.ShowfullpeakButton.Value == true
                axish=max(maxy)*1.1;
            elseif app.CroplargepeakButton.Value == true
                axish=((cfn)*sp);
            end
            xrange=(max(maxx)-min(minx));
            if app.ForcexaxisextentCheckBox.Value == true
                xtend=app.EditField.Value;
            else
                xtend=(max(maxx)+xrange/50);
            end
            axis([(min(minx)-xrange/50),xtend,0,axish]);
            print(gcf,figtitle,'-dpng')
            hold off
            close
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.ProcessingLabel.Visible = 'off';
            app.FinishedLabel.Visible = 'on';
        end

        % Value changed function: ChangewavelenthCheckBox
        function ChangewavelenthCheckBoxValueChanged(app, event)
            if  app.ChangewavelenthCheckBox.Value == true
                app.TargetwavelengthEditField.Visible = 'on';
                app.TargetwavelengthEditFieldLabel.Visible = 'on';
                app.OriginalwavelengthEditField.Visible = 'on';
                app.OriginalwavelengthEditFieldLabel.Visible = 'on';
            else
                app.TargetwavelengthEditField.Visible = 'off';
                app.TargetwavelengthEditFieldLabel.Visible = 'off';   
                app.OriginalwavelengthEditField.Visible = 'off';
                app.OriginalwavelengthEditFieldLabel.Visible = 'off';
            end
        end

        % Value changed function: FilefilterEditField
        function FilefilterEditFieldValueChanged(app, event)

        end

        % Value changed function: OriginalwavelengthEditField
        function OriginalwavelengthEditFieldValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalwavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.OriginalwavelengthEditFieldLabel.Text = 'Original wavelength';
            app.TargetwavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.TargetwavelengthEditFieldLabel.Text = 'Target wavelength';
            app.HeaderlinesEditFieldLabel = uilabel(app.UIFigure);
            app.HeaderlinesEditFieldLabel.Text = 'Header lines';
            app.CropfilenameEditFieldLabel = uilabel(app.UIFigure);
            app.CropfilenameEditFieldLabel.Text = 'Crop filename';
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Tooltip = {''};
            app.dEditFieldLabel.Text = 'd';
            app.hEditFieldLabel = uilabel(app.UIFigure);
            app.hEditFieldLabel.Tooltip = {''};
            app.hEditFieldLabel.Text = 'h';
            app.FilefilterEditFieldLabel = uilabel(app.UIFigure);
            app.FilefilterEditFieldLabel.Text = 'File filter';
            app.InstructionsLabel = uilabel(app.UIFigure);
            app.InstructionsLabel.Tooltip = {'Put raw files in the same folder'; 'Adjust the parameters and press "RUN".'; 'All figure windows will close automatically after finished'; 'The .csv files with converted wavelength and image files for each profile would be saved in the same folder with an additional All_profiles image'; 'Check the results and adjust the parameters again (especially d & h)'};
            app.InstructionsLabel.Text = '[Instructions]';
            app.FilefilterEditField = uieditfield(app.UIFigure, 'text');
            app.FilefilterEditField.ValueChangedFcn = createCallbackFcn(app, @FilefilterEditFieldValueChanged, true);
            app.FilefilterEditField.Tooltip = {'*.filename extension, or fillter files like "sample_1_point_*.csv", where "*" is wildcard character.'};
            app.FilefilterEditField.Value = '*.xye';
            app.HeaderlinesEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeaderlinesEditField.ValueDisplayFormat = '%.0f';
            app.HeaderlinesEditField.Tooltip = {'Input non-negative integers, check raw files.'};
            app.HeaderlinesEditField.Value = 3;
            app.CropfilenameEditField = uieditfield(app.UIFigure, 'numeric');
            app.CropfilenameEditField.ValueDisplayFormat = '%.0f';
            app.CropfilenameEditField.Tooltip = {'Input non-negative integers, e.g. remeving "_mar3450_A92.xye" needs 16 characters.'};
            app.CropfilenameEditField.Value = 16;
            app.ChangewavelenthCheckBox = uicheckbox(app.UIFigure);
            app.ChangewavelenthCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangewavelenthCheckBoxValueChanged, true);
            app.ChangewavelenthCheckBox.Tooltip = {'For XRD'};
            app.ChangewavelenthCheckBox.Text = 'Change wavelenth';
            app.ChangewavelenthCheckBox.Value = true;
            app.OriginalwavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.OriginalwavelengthEditField.ValueDisplayFormat = '%3.10g';
            app.OriginalwavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @OriginalwavelengthEditFieldValueChanged, true);
            app.OriginalwavelengthEditField.Tooltip = {'Such as synchrotron X-ray wavelength.'};
            app.OriginalwavelengthEditField.Value = 0.77491;
            app.TargetwavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetwavelengthEditField.ValueDisplayFormat = '%3.10g';
            app.TargetwavelengthEditField.Tooltip = {'Such as in house X-ray wavelength Cu Kα1 1.540598 Å'};
            app.TargetwavelengthEditField.Value = 1.540598;
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditField.ValueDisplayFormat = '%.0f';
            app.dEditField.Tooltip = {'Input natural number, compare to data how many points away.'};
            app.dEditField.Value = 2;
            app.hEditField = uieditfield(app.UIFigure, 'numeric');
            app.hEditField.Tooltip = {'Input floating point, how much higher than those data points.'; ' '; 'strong peak: small d large h; broad peak: large d small h.'};
            app.hEditField.Value = 1.2;
            app.show2thetaCheckBox = uicheckbox(app.UIFigure);
            app.show2thetaCheckBox.Text = 'show 2theta';
            app.show2thetaCheckBox.Value = true;
            app.showdspacingCheckBox = uicheckbox(app.UIFigure);
            app.showdspacingCheckBox.Text = 'show d-spacing';
            app.showdspacingCheckBox.Value = true;
            app.PrecisionEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrecisionEditField.ValueDisplayFormat = '%.0f';
            app.PrecisionEditField.Tooltip = {'Input non-negative integers, accuracy, default 0.01'};
            app.PrecisionEditField.Value = 2;
            app.ForcexaxisextentCheckBox = uicheckbox(app.UIFigure);
            app.ForcexaxisextentCheckBox.Text = 'Force x axis extent';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 50;
            app.PrecisionEditFieldLabel = uilabel(app.UIFigure);
            app.PrecisionEditFieldLabel.Text = 'Precision ';
            app.All_profilesimageButtonGroup = uibuttongroup(app.UIFigure);
            app.All_profilesimageButtonGroup.Title = 'All_profiles image';
            app.ShowfullpeakButton = uiradiobutton(app.All_profilesimageButtonGroup);
            app.ShowfullpeakButton.Text = 'Show full peak';
            app.ShowfullpeakButton.Value = true;
            app.CroplargepeakButton = uiradiobutton(app.All_profilesimageButtonGroup);
            app.CroplargepeakButton.Text = 'Crop large peak';
            app.IntervalEditField = uieditfield(app.All_profilesimageButtonGroup, 'numeric');
            app.IntervalEditField.Tooltip = {'For all_profiles, space between each profile'};
            app.IntervalEditField.Value = 50;
            app.IntervalEditFieldLabel = uilabel(app.All_profilesimageButtonGroup);
            app.IntervalEditFieldLabel.Text = 'Interval';
            app.ProcessingLabel = uilabel(app.UIFigure);
            app.ProcessingLabel.Visible = 'off';
            app.ProcessingLabel.Text = 'Processing';
            app.FinishedLabel = uilabel(app.UIFigure);
            app.FinishedLabel.Visible = 'off';
            app.FinishedLabel.Text = 'Finished';
            app.plotpeak_v15Label = uilabel(app.UIFigure);
            app.plotpeak_v15Label.Tooltip = {'By TarranChen (tzeyuanchen@gmail.com)'};
            app.plotpeak_v15Label.Text = 'plotpeak_v1.5';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotpeak_v1_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end